classdef GUI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FDTD3DUIFigure               matlab.ui.Figure
        PlotButton                   matlab.ui.control.Button
        relativeEditField_2Label     matlab.ui.control.Label
        relativeEditField_2          matlab.ui.control.NumericEditField
        relativeEditFieldLabel       matlab.ui.control.Label
        relativeEditField            matlab.ui.control.NumericEditField
        PlatewidthmEditFieldLabel    matlab.ui.control.Label
        PlatewidthmEditField         matlab.ui.control.NumericEditField
        PlatelengthmEditFieldLabel   matlab.ui.control.Label
        PlatelengthmEditField        matlab.ui.control.NumericEditField
        SeparationmEditFieldLabel    matlab.ui.control.Label
        SeparationmEditField         matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        FrequencyHzEditField         matlab.ui.control.NumericEditField
        ModeEditFieldLabel           matlab.ui.control.Label
        ModeEditField                matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        ModeButtonGroup              matlab.ui.container.ButtonGroup
        TEButton                     matlab.ui.control.RadioButton
        TMButton                     matlab.ui.control.RadioButton
        InitialConditionButtonGroup  matlab.ui.container.ButtonGroup
        AnalyticalvalueButton        matlab.ui.control.RadioButton
        ZeroButton                   matlab.ui.control.RadioButton
        ParallelplateWaveguideLabel  matlab.ui.control.Label
        WavepropagationinwaveguideLabel  matlab.ui.control.Label
        NoteAllplotsshowHyforTEmodeLabel  matlab.ui.control.Label
        zdirectionLabel              matlab.ui.control.Label
        ydirectionLabel              matlab.ui.control.Label
    end


    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Get app user input
            mu_r = app.relativeEditField_2.Value;
            epsi_r = app.relativeEditField.Value;
            f_max = app.FrequencyHzEditField.Value;
            W = app.PlatewidthmEditField.Value;
            L = app.PlatelengthmEditField.Value;
            d = app.SeparationmEditField.Value;
            m = app.ModeEditField.Value;
            
            hfdtd = animatedline(app.UIAxes);
            hanaly = animatedline(app.UIAxes2);
            %herr = animatedline(app.UIAxes3);
           % f = app.FDTD3DUIFigure;
            ax3 = app.UIAxes3;
            ax3.Visible = 'off'
            
            if(app.AnalyticalvalueButton.Value == true)
                initWithAnaly = 1;
            else
                initWithAnaly = 0;
            end
            
            colorbar(app.UIAxes3)
            
            % show the result:
            if(app.TEButton.Value == true)
                FDTD_3D_Waveguide_For_Plot_TE(mu_r, epsi_r, f_max, W, L, d, m, hfdtd, hanaly, ax3, initWithAnaly);
            else
                FDTD_3D_Waveguide_For_Plot_TM(mu_r, epsi_r, f_max, W, L, d, m, hfdtd, hanaly, ax3, initWithAnaly);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FDTD3DUIFigure = uifigure;
            app.FDTD3DUIFigure.Name = 'FDTD3D';
            app.PlotButton = uibutton(app.FDTD3DUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.relativeEditField_2Label = uilabel(app.FDTD3DUIFigure);
            app.relativeEditField_2Label.Text = 'μ (relative)';
            app.relativeEditField_2 = uieditfield(app.FDTD3DUIFigure, 'numeric');
            app.relativeEditField_2.Value = 1;
            app.relativeEditFieldLabel = uilabel(app.FDTD3DUIFigure);
            app.relativeEditFieldLabel.Text = 'ε (relative)';
            app.relativeEditField = uieditfield(app.FDTD3DUIFigure, 'numeric');
            app.relativeEditField.Value = 1;
            app.PlatewidthmEditFieldLabel = uilabel(app.FDTD3DUIFigure);
            app.PlatewidthmEditFieldLabel.Text = 'Plate width (m)';
            app.PlatewidthmEditField = uieditfield(app.FDTD3DUIFigure, 'numeric');
            app.PlatewidthmEditField.Value = 1;
            app.PlatelengthmEditFieldLabel = uilabel(app.FDTD3DUIFigure);
            app.PlatelengthmEditFieldLabel.Text = 'Plate length (m)';
            app.PlatelengthmEditField = uieditfield(app.FDTD3DUIFigure, 'numeric');
            app.PlatelengthmEditField.Value = 0.1;
            app.SeparationmEditFieldLabel = uilabel(app.FDTD3DUIFigure);
            app.SeparationmEditFieldLabel.Text = 'Separation (m)';
            app.SeparationmEditField = uieditfield(app.FDTD3DUIFigure, 'numeric');
            app.SeparationmEditField.Value = 0.05;
            app.FrequencyHzEditFieldLabel = uilabel(app.FDTD3DUIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.FDTD3DUIFigure, 'numeric');
            app.FrequencyHzEditField.Limits = [-Inf 100000000000];
            app.FrequencyHzEditField.Value = 10000000000;
            app.ModeEditFieldLabel = uilabel(app.FDTD3DUIFigure);
            app.ModeEditFieldLabel.Text = ' Mode';
            app.ModeEditField = uieditfield(app.FDTD3DUIFigure, 'numeric');
            app.ModeEditField.Value = 1;
            app.UIAxes = uiaxes(app.FDTD3DUIFigure);
            title(app.UIAxes, 'FDTD')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.FDTD3DUIFigure);
            title(app.UIAxes2, 'Analytical')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            app.UIAxes3 = uiaxes(app.FDTD3DUIFigure);
            title(app.UIAxes3, 'y-z cut (Hy for TE, Ey for TM)')
            xlabel(app.UIAxes3, 'z')
            ylabel(app.UIAxes3, 'y')
            app.UIAxes3.Visible = 'off';
            app.ModeButtonGroup = uibuttongroup(app.FDTD3DUIFigure);
            app.ModeButtonGroup.Title = 'Mode';
            app.TEButton = uiradiobutton(app.ModeButtonGroup);
            app.TEButton.Text = 'TE';
            app.TEButton.Value = true;
            app.TMButton = uiradiobutton(app.ModeButtonGroup);
            app.TMButton.Text = 'TM';
            app.InitialConditionButtonGroup = uibuttongroup(app.FDTD3DUIFigure);
            app.InitialConditionButtonGroup.Title = 'Initial Condition';
            app.AnalyticalvalueButton = uiradiobutton(app.InitialConditionButtonGroup);
            app.AnalyticalvalueButton.Text = 'Analytical value';
            app.AnalyticalvalueButton.Value = true;
            app.ZeroButton = uiradiobutton(app.InitialConditionButtonGroup);
            app.ZeroButton.Text = 'Zero';
            app.ParallelplateWaveguideLabel = uilabel(app.FDTD3DUIFigure);
            app.ParallelplateWaveguideLabel.Text = 'Parallel plate Waveguide';
            app.WavepropagationinwaveguideLabel = uilabel(app.FDTD3DUIFigure);
            app.WavepropagationinwaveguideLabel.Text = 'Wave propagation in waveguide';
            app.NoteAllplotsshowHyforTEmodeLabel = uilabel(app.FDTD3DUIFigure);
            app.NoteAllplotsshowHyforTEmodeLabel.Text = 'Note: All plots show Hy for TE mode/ Ey for TM mode. Wave propagation in +y direction.';
            app.zdirectionLabel = uilabel(app.FDTD3DUIFigure);
            app.zdirectionLabel.Text = 'z-direction';
            app.ydirectionLabel = uilabel(app.FDTD3DUIFigure);
            app.ydirectionLabel.Text = 'y-direction';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FDTD3DUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FDTD3DUIFigure)
        end
    end
end